classdef self_balancing_robot_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_Status           matlab.ui.container.Panel
        Lamp_RcModeStatus      matlab.ui.control.Lamp
        SerialLabel_2          matlab.ui.control.Label
        Lamp_SerialStatus      matlab.ui.control.Lamp
        SerialLabel            matlab.ui.control.Label
        Panel_ControlAction    matlab.ui.container.Panel
        Gauge_LeftWheel        matlab.ui.control.LinearGauge
        LabelGauge_RightWheel  matlab.ui.control.Label
        LabelGauge_LeftWheel   matlab.ui.control.Label
        Gauge_RightWheel       matlab.ui.control.LinearGauge
        Panel_UserCommands     matlab.ui.container.Panel
        Gauge_Yaw              matlab.ui.control.LinearGauge
        LabelGauge_Yaw         matlab.ui.control.Label
        LabelGauge_Throttle    matlab.ui.control.Label
        Gauge_Throttle         matlab.ui.control.LinearGauge
        Panel_Serial           matlab.ui.container.Panel
        DropDown_SerialPorts   matlab.ui.control.DropDown
        PortsDropDownLabel     matlab.ui.control.Label
        Button_SerialConnect   matlab.ui.control.Button
        Label_Tittle           matlab.ui.control.Label
        Image_ETSIDI           matlab.ui.control.Image
        Image_upm              matlab.ui.control.Image
        Graph_Pitch            matlab.ui.control.UIAxes
        Graph_Yaw              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serial_port;
        serial_status;
        first_connection;
        data_idx;
        serial_commandThrottle;
        serial_commandYaw;
        serial_RcMode;
    end
    
    methods (Access = private)
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Complete the combo box with the available ports
            portList = serialportlist;
            app.DropDown_SerialPorts.Items = portList;

            % Configure the gauge
            app.Gauge_Throttle.Limits = [-100 100];
            app.Gauge_Yaw.Limits = [-100 100];

            % Configure the graphic
            %ylim(app.UIAxes, [-360, 360]);
            %xlim(app.UIAxes, [0, 10]);

            % Initialize the lamps
            app.Lamp_SerialStatus.Color = 'r';
            app.Lamp_RcModeStatus.Color = 'r';

            % Initialize attributes
            app.first_connection = true;
            app.serial_status = false;
            app.data_idx=1;
            app.serial_commandThrottle = 0;
            app.serial_commandYaw = 0;
        end

        % Button pushed function: Button_SerialConnect
        function Button_SerialConnectPushed(app, event)
                        % Activate status to true
            app.serial_status = true;
            app.Lamp_SerialStatus.Color = 'g';

            if(app.first_connection == true)
                % Connect to the serial port selected in the comboBox
                app.serial_port=serialport(app.DropDown_SerialPorts.Value,115200);
                configureTerminator(app.serial_port,"CR/LF");
                app.first_connection = false;
            end

            % Initialize the data for the plot before entering the loop
            angles = []; % Stores angle values for the plot
            idx = [];    % Stores indices (time or iterations)
            
            while(app.serial_status == true)
                % Read the serial port data
                flush(app.serial_port);
                serial_data = readline(app.serial_port);
            
                % Split the data by the comma
                split_serial_data = split(serial_data, ',');
            
                % Check if the data was properly split into two parts
                if numel(split_serial_data) == 3
                    % Convert and assign the data
                    app.serial_commandYaw = str2double(split_serial_data{1});  
                    app.serial_commandThrottle = str2double(split_serial_data{2});
                    app.serial_RcMode = str2double(split_serial_data{3});

                    if ~isnan(app.serial_RcMode)
                        if isfinite(app.serial_RcMode)
                            if app.serial_RcMode==1
                                app.Lamp_RcModeStatus.Color = 'g';
                            else
                                app.Lamp_RcModeStatus.Color = 'r';
                            end
                        else
                            app.Lamp_RcModeStatus.Color = 'r'; 
                            warning('Invalid RC Mode value received. Setting gauge to default value (0).');
                        end
                    else

                    end
         
                    % Verify if the data is valid
                    if ~isnan(app.serial_commandThrottle) && ~isnan(app.serial_commandYaw) && app.serial_RcMode==1

                        % Update the Gauges
                        if isfinite(app.serial_commandYaw)
                            app.Gauge_Yaw.Value = app.serial_commandYaw;
                        else
                            app.Gauge_Yaw.Value = 0; 
                            warning('Invalid Yaw command value received. Setting gauge to default value (0).');
                        end

                        if isfinite(app.serial_commandThrottle)
                            app.Gauge_Throttle.Value = app.serial_commandThrottle;
                        else
                            app.Gauge_Throttle.Value = 0; 
                            warning('Invalid Throttle command value received. Setting gauge to default value (0).');
                        end

                    else
                        % Log a warning if the received data is invalid
                        warning('Invalid numeric data received: %s', serial_data);
                    end
                else
                    % Log a warning if the data is not in the expected format
                    warning('Invalid serial data format: %s', serial_data);
                end
            
                % Pause to simulate the sampling frequency
                pause(0.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph_Yaw = uiaxes(app.UIFigure);
            xlabel(app.Graph_Yaw, 'Time [s]')
            ylabel(app.Graph_Yaw, 'Yaw [deg]')
            zlabel(app.Graph_Yaw, 'Z')
            app.Graph_Pitch = uiaxes(app.UIFigure);
            xlabel(app.Graph_Pitch, 'Time [s]')
            ylabel(app.Graph_Pitch, 'Pitch [deg]')
            zlabel(app.Graph_Pitch, 'Z')
            app.Image_upm = uiimage(app.UIFigure);
            app.Image_upm.ImageSource = fullfile(pathToMLAPP, 'upm_logo.png');
            app.Image_ETSIDI = uiimage(app.UIFigure);
            app.Image_ETSIDI.ImageSource = fullfile(pathToMLAPP, 'etsidi.png');
            app.Label_Tittle = uilabel(app.UIFigure);
            app.Label_Tittle.Text = 'SELF-BALANCING ROBOT INTERFACE';
            app.Panel_Serial = uipanel(app.UIFigure);
            app.Panel_Serial.Title = 'Serial Connection';
            app.Button_SerialConnect = uibutton(app.Panel_Serial, 'push');
            app.Button_SerialConnect.ButtonPushedFcn = createCallbackFcn(app, @Button_SerialConnectPushed, true);
            app.Button_SerialConnect.Text = 'Connect';
            app.PortsDropDownLabel = uilabel(app.Panel_Serial);
            app.PortsDropDownLabel.Text = 'Ports';
            app.DropDown_SerialPorts = uidropdown(app.Panel_Serial);
            app.Panel_UserCommands = uipanel(app.UIFigure);
            app.Panel_UserCommands.Title = 'User Commands';
            app.Gauge_Throttle = uigauge(app.Panel_UserCommands, 'linear');
            app.Gauge_Throttle.Limits = [-100 100];
            app.Gauge_Throttle.MajorTicks = [-100 -50 0 50 100];
            app.Gauge_Throttle.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.LabelGauge_Throttle = uilabel(app.Panel_UserCommands);
            app.LabelGauge_Throttle.Text = 'Throttle';
            app.LabelGauge_Yaw = uilabel(app.Panel_UserCommands);
            app.LabelGauge_Yaw.Text = 'Yaw';
            app.Gauge_Yaw = uigauge(app.Panel_UserCommands, 'linear');
            app.Gauge_Yaw.Limits = [-100 100];
            app.Gauge_Yaw.MajorTicks = [-100 -50 0 50 100];
            app.Gauge_Yaw.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.Panel_ControlAction = uipanel(app.UIFigure);
            app.Panel_ControlAction.Title = 'Control Action';
            app.Gauge_RightWheel = uigauge(app.Panel_ControlAction, 'linear');
            app.Gauge_RightWheel.Limits = [-255 255];
            app.Gauge_RightWheel.MajorTicks = [-255 -125 0 125 255];
            app.LabelGauge_LeftWheel = uilabel(app.Panel_ControlAction);
            app.LabelGauge_LeftWheel.Text = 'Left Wheel';
            app.LabelGauge_RightWheel = uilabel(app.Panel_ControlAction);
            app.LabelGauge_RightWheel.Text = 'Right Wheel';
            app.Gauge_LeftWheel = uigauge(app.Panel_ControlAction, 'linear');
            app.Gauge_LeftWheel.Limits = [-255 255];
            app.Gauge_LeftWheel.MajorTicks = [-255 -125 0 125 255];
            app.Panel_Status = uipanel(app.UIFigure);
            app.Panel_Status.Title = 'Status';
            app.SerialLabel = uilabel(app.Panel_Status);
            app.SerialLabel.Text = 'Serial';
            app.Lamp_SerialStatus = uilamp(app.Panel_Status);
            app.SerialLabel_2 = uilabel(app.Panel_Status);
            app.SerialLabel_2.Text = 'RC Mode';
            app.Lamp_RcModeStatus = uilamp(app.Panel_Status);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = self_balancing_robot_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end